classdef Simulink_asserv_Disc_28Mars_V2023a_SLSimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                           matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveSession                   matlab.ui.container.Menu
        SaveParametersAndInputs       matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        LoadSession                   matlab.ui.container.Menu
        LoadParametersAndInputs       matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        ConfigureForDeploymentMenu    matlab.ui.container.Menu
        ResetParametersAndInputs      matlab.ui.container.Menu
        MainGL                        matlab.ui.container.GridLayout
        SimControlsGL                 matlab.ui.container.GridLayout
        SimTimeLabel                  matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        StopTimeGL                    matlab.ui.container.GridLayout
        StopTimeEditField             matlab.ui.control.EditField
        StopTimeEditFieldLabel        matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        RunPauseResumeButton          matlab.ui.control.Button
        ModelHTMLAndPrmsGLShuttersGL  matlab.ui.container.GridLayout
        ModelAndVariablesView         matlab.ui.container.TabGroup
        ModelTab                      matlab.ui.container.Tab
        ModelGL                       matlab.ui.container.GridLayout
        ModelHTML                     matlab.ui.control.HTML
        PrmsGL                        matlab.ui.container.GridLayout
        ModelHTMLAndPrmsGLShutterControlsGL  matlab.ui.container.GridLayout
        ScrollTopRightUIC             matlab.ui.control.Button
        ScrollTopLeftUIC              matlab.ui.control.Button
        ControlsGL                    matlab.ui.container.GridLayout
        ScrollDownUIC                 matlab.ui.control.Button
        ScrollUpUIC                   matlab.ui.control.Button
        ResultsGL                     matlab.ui.container.GridLayout
        SigsGLAndAxesGLShuttersGL     matlab.ui.container.GridLayout
        AxesGL                        matlab.ui.container.GridLayout
        SigsGL                        matlab.ui.container.GridLayout
        SigsGLAndAxesGLShutterControlsGL  matlab.ui.container.GridLayout
        ScrollBottomRightUIC          matlab.ui.control.Button
        ScrollBottomLeftUIC           matlab.ui.control.Button
        PlotControlsGL                matlab.ui.container.GridLayout
        PlotLayoutOuterGL             matlab.ui.container.GridLayout
        PlotLayoutGL                  matlab.ui.container.GridLayout
        NumAxesCols                   matlab.ui.control.Spinner
        NumAxesRows                   matlab.ui.control.Spinner
        PlotLayoutLabel               matlab.ui.control.Label
        PlotRefreshRateHzSliderLabel  matlab.ui.control.Label
        PlotRefreshRateHzSlider       matlab.ui.control.Slider
    end

    properties (Access = public)
        debugTrace = false % set to true to enable printing debug messages
        modelData
    end

    properties (Access = ?AppHelper)
        initialized = false
        selectedAxes matlab.ui.control.UIAxes
        refreshTimer timer
        flashMsgTimer timer
        simStatus = slsim.SimulationStatus.Inactive
        Helper
    end

    methods (Access = ?AppHelper)
        function fcn = createCallbackFcnProxy(app, callback, requiresEventData)
            fcn = app.createCallbackFcn(callback, requiresEventData);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Helper = AppHelper(app);
            app.Helper.startupFcn();
        end

        % Button pushed function: ScrollTopLeftUIC
        function ScrollTopLeftUICPushed(app, event)
            app.Helper.scroll(event.Source);
        end

        % Button pushed function: ScrollTopRightUIC
        function ScrollTopRightUICPushed(app, event)
            app.Helper.scroll(event.Source);
        end

        % Button pushed function: ScrollBottomRightUIC
        function ScrollBottomRightUICButtonPushed(app, event)
            app.Helper.scroll(event.Source);
        end

        % Button pushed function: ScrollBottomLeftUIC
        function ScrollBottomLeftUICButtonPushed(app, event)
            app.Helper.scroll(event.Source);
        end

        % Button pushed function: ScrollDownUIC
        function ScrollDownUICButtonPushed(app, event)
            app.Helper.scroll(event.Source);
        end

        % Button pushed function: ScrollUpUIC
        function ScrollUpUICButtonPushed(app, event)
            app.Helper.scroll(event.Source);
        end

        % Size changed function: GUI
        function GUISizeChanged(app, event)
            if ~app.initialized, return; end
            app.Helper.setMainGLRowHeight();
        end

        % Value changed function: NumAxesRows
        function NumAxesRowsValueChanged(app, event)
            app.Helper.numAxesRowsValueChanged(event);
        end

        % Value changed function: NumAxesCols
        function NumAxesColsValueChanged(app, event)
            app.Helper.numAxesColsValueChanged(event);
        end

        % Button pushed function: RunPauseResumeButton
        function RunPauseResumeButtonPushed(app, event)
            app.Helper.runPauseResumeButtonPushed(event);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Helper.stopButtonPushed(event);
        end

        % Close request function: GUI
        function GUICloseRequest(app, event)
            app.Helper.guiCloseRequest(event);
        end

        % Value changed function: PlotRefreshRateHzSlider
        function PlotRefreshRateHzSliderValueChanged(app, event)
            app.Helper.plotRefreshRateHzSliderValueChanged();
        end

        % Menu selected function: ConfigureForDeploymentMenu
        function ConfigureForDeploymentMenuSelected(app, event)
            app.Helper.toggleConfigureForDeployment();
        end

        % Menu selected function: SaveSession
        function SaveSessionMenuSelected(app, event)
            app.Helper.saveSession();
        end

        % Menu selected function: SaveParametersAndInputs
        function SaveParametersAndInputsMenuSelected(app, event)
            app.Helper.saveParametersAndInputs();
        end

        % Menu selected function: LoadSession
        function LoadSessionMenuSelected(app, event)
            app.Helper.loadSession();
        end

        % Menu selected function: LoadParametersAndInputs
        function LoadParametersAndInputsMenuSelected(app, event)
            app.Helper.loadParametersAndInputs();
        end

        % Menu selected function: ResetParametersAndInputs
        function ResetParametersAndInputsMenuSelected(app, event)
            app.Helper.resetParametersAndInputs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'MATLAB App';
            app.GUI.CloseRequestFcn = createCallbackFcn(app, @GUICloseRequest, true);
            app.GUI.SizeChangedFcn = createCallbackFcn(app, @GUISizeChanged, true);
            app.FileMenu = uimenu(app.GUI);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveSession = uimenu(app.SaveMenu);
            app.SaveSession.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionMenuSelected, true);
            app.SaveSession.Text = 'Session';
            app.SaveParametersAndInputs = uimenu(app.SaveMenu);
            app.SaveParametersAndInputs.MenuSelectedFcn = createCallbackFcn(app, @SaveParametersAndInputsMenuSelected, true);
            app.SaveParametersAndInputs.Text = 'Parameters and Inputs';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.LoadSession = uimenu(app.LoadMenu);
            app.LoadSession.MenuSelectedFcn = createCallbackFcn(app, @LoadSessionMenuSelected, true);
            app.LoadSession.Text = 'Session';
            app.LoadParametersAndInputs = uimenu(app.LoadMenu);
            app.LoadParametersAndInputs.MenuSelectedFcn = createCallbackFcn(app, @LoadParametersAndInputsMenuSelected, true);
            app.LoadParametersAndInputs.Text = 'Parameters and Inputs';
            app.SettingsMenu = uimenu(app.GUI);
            app.SettingsMenu.Text = 'Settings';
            app.ConfigureForDeploymentMenu = uimenu(app.SettingsMenu);
            app.ConfigureForDeploymentMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureForDeploymentMenuSelected, true);
            app.ConfigureForDeploymentMenu.Checked = 'on';
            app.ConfigureForDeploymentMenu.Text = 'Configure for deployment';
            app.ResetParametersAndInputs = uimenu(app.SettingsMenu);
            app.ResetParametersAndInputs.MenuSelectedFcn = createCallbackFcn(app, @ResetParametersAndInputsMenuSelected, true);
            app.ResetParametersAndInputs.Text = 'Reset Parameters and Inputs';
            app.MainGL = uigridlayout(app.GUI);
            app.ResultsGL = uigridlayout(app.MainGL);
            app.ResultsGL.Tag = 'ResultsGL';
            app.PlotControlsGL = uigridlayout(app.ResultsGL);
            app.PlotRefreshRateHzSlider = uislider(app.PlotControlsGL);
            app.PlotRefreshRateHzSlider.Limits = [-2 2];
            app.PlotRefreshRateHzSlider.MajorTicks = [-2 -1 0 1 2];
            app.PlotRefreshRateHzSlider.MajorTickLabels = {'1/100', '1/10', '1', '10', '100'};
            app.PlotRefreshRateHzSlider.ValueChangedFcn = createCallbackFcn(app, @PlotRefreshRateHzSliderValueChanged, true);
            app.PlotRefreshRateHzSlider.MinorTicks = [];
            app.PlotRefreshRateHzSlider.Tag = 'PlotRefreshRateHzSlider';
            app.PlotRefreshRateHzSliderLabel = uilabel(app.PlotControlsGL);
            app.PlotRefreshRateHzSliderLabel.Text = 'Refresh Rate (Hz) ';
            app.PlotLayoutOuterGL = uigridlayout(app.PlotControlsGL);
            app.PlotLayoutGL = uigridlayout(app.PlotLayoutOuterGL);
            app.PlotLayoutLabel = uilabel(app.PlotLayoutGL);
            app.PlotLayoutLabel.Text = 'Plot Layout ';
            app.NumAxesRows = uispinner(app.PlotLayoutGL);
            app.NumAxesRows.Limits = [1 10];
            app.NumAxesRows.ValueDisplayFormat = '%.0f';
            app.NumAxesRows.ValueChangedFcn = createCallbackFcn(app, @NumAxesRowsValueChanged, true);
            app.NumAxesRows.Value = 1;
            app.NumAxesCols = uispinner(app.PlotLayoutGL);
            app.NumAxesCols.Limits = [1 10];
            app.NumAxesCols.ValueDisplayFormat = '%.0f';
            app.NumAxesCols.ValueChangedFcn = createCallbackFcn(app, @NumAxesColsValueChanged, true);
            app.NumAxesCols.Value = 1;
            app.SigsGLAndAxesGLShuttersGL = uigridlayout(app.ResultsGL);
            app.SigsGLAndAxesGLShutterControlsGL = uigridlayout(app.SigsGLAndAxesGLShuttersGL);
            app.ScrollBottomLeftUIC = uibutton(app.SigsGLAndAxesGLShutterControlsGL, 'push');
            app.ScrollBottomLeftUIC.ButtonPushedFcn = createCallbackFcn(app, @ScrollBottomLeftUICButtonPushed, true);
            app.ScrollBottomLeftUIC.Tag = 'ScrollBottomLeftUIC';
            app.ScrollBottomLeftUIC.Text = '';
            app.ScrollBottomRightUIC = uibutton(app.SigsGLAndAxesGLShutterControlsGL, 'push');
            app.ScrollBottomRightUIC.ButtonPushedFcn = createCallbackFcn(app, @ScrollBottomRightUICButtonPushed, true);
            app.ScrollBottomRightUIC.Tag = 'ScrollBottomRightUIC';
            app.ScrollBottomRightUIC.Text = '';
            app.SigsGL = uigridlayout(app.SigsGLAndAxesGLShuttersGL);
            app.SigsGL.Tag = 'SigsGL';
            app.AxesGL = uigridlayout(app.SigsGLAndAxesGLShuttersGL);
            app.AxesGL.Tag = 'AxesGL';
            app.ControlsGL = uigridlayout(app.MainGL);
            app.ScrollUpUIC = uibutton(app.ControlsGL, 'push');
            app.ScrollUpUIC.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpUICButtonPushed, true);
            app.ScrollUpUIC.Tag = 'ScrollUpUIC';
            app.ScrollUpUIC.Text = '';
            app.ScrollDownUIC = uibutton(app.ControlsGL, 'push');
            app.ScrollDownUIC.ButtonPushedFcn = createCallbackFcn(app, @ScrollDownUICButtonPushed, true);
            app.ScrollDownUIC.Tag = 'ScrollDownUIC';
            app.ScrollDownUIC.Text = '';
            app.ModelHTMLAndPrmsGLShuttersGL = uigridlayout(app.MainGL);
            app.ModelHTMLAndPrmsGLShuttersGL.Tag = 'ModelHTMLAndPrmsGLShuttersGL';
            app.ModelHTMLAndPrmsGLShutterControlsGL = uigridlayout(app.ModelHTMLAndPrmsGLShuttersGL);
            app.ScrollTopLeftUIC = uibutton(app.ModelHTMLAndPrmsGLShutterControlsGL, 'push');
            app.ScrollTopLeftUIC.ButtonPushedFcn = createCallbackFcn(app, @ScrollTopLeftUICPushed, true);
            app.ScrollTopLeftUIC.Tag = 'ScrollTopLeftUIC';
            app.ScrollTopLeftUIC.Text = '';
            app.ScrollTopRightUIC = uibutton(app.ModelHTMLAndPrmsGLShutterControlsGL, 'push');
            app.ScrollTopRightUIC.ButtonPushedFcn = createCallbackFcn(app, @ScrollTopRightUICPushed, true);
            app.ScrollTopRightUIC.Tag = 'ScrollTopRightUIC';
            app.ScrollTopRightUIC.Text = '';
            app.PrmsGL = uigridlayout(app.ModelHTMLAndPrmsGLShuttersGL);
            app.PrmsGL.Tag = 'PrmsGL';
            app.ModelAndVariablesView = uitabgroup(app.ModelHTMLAndPrmsGLShuttersGL);
            app.ModelAndVariablesView.Tag = 'ModelHTML';
            app.ModelTab = uitab(app.ModelAndVariablesView);
            app.ModelTab.Title = 'Model';
            app.ModelGL = uigridlayout(app.ModelTab);
            app.ModelHTML = uihtml(app.ModelGL);
            app.SimControlsGL = uigridlayout(app.MainGL);
            app.RunPauseResumeButton = uibutton(app.SimControlsGL, 'push');
            app.RunPauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @RunPauseResumeButtonPushed, true);
            app.RunPauseResumeButton.Text = '';
            app.StopButton = uibutton(app.SimControlsGL, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.StopTimeGL = uigridlayout(app.SimControlsGL);
            app.StopTimeEditFieldLabel = uilabel(app.StopTimeGL);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.StopTimeGL, 'text');
            app.StopTimeEditField.Value = '20';
            app.StatusLabel = uilabel(app.SimControlsGL);
            app.StatusLabel.Text = '';
            app.SimTimeLabel = uilabel(app.SimControlsGL);
            app.SimTimeLabel.Text = '';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulink_asserv_Disc_28Mars_V2023a_SLSimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end